classdef Spring_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExportasSTLButton              matlab.ui.control.Button
        EndConditionDropDown           matlab.ui.control.DropDown
        EndConditionDropDownLabel      matlab.ui.control.Label
        ModulusofrigidityGEditField    matlab.ui.control.NumericEditField
        ModulusofrigidityGEditFieldLabel  matlab.ui.control.Label
        UTSNmmEditField                matlab.ui.control.NumericEditField
        UTSLabel                       matlab.ui.control.Label
        CalculateotherValueGenerateCADButton  matlab.ui.control.Button
        DefineConstraintsLabel         matlab.ui.control.Label
        SpecifyMaterialItsPropertiesLabel  matlab.ui.control.Label
        SpringIndexCEditField          matlab.ui.control.NumericEditField
        SpringIndexCEditFieldLabel     matlab.ui.control.Label
        MaxDeflectionmmEditField       matlab.ui.control.NumericEditField
        MaxDeflectionmmEditFieldLabel  matlab.ui.control.Label
        MaxSpringForceNEditField       matlab.ui.control.NumericEditField
        MaxSpringForceNEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        A
        B
        C
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalculateotherValueGenerateCADButton, UIAxes
        function UIAxesButtonDown(app, event)
            app.UIAxes.Visible = 'on';
            
            P = app.MaxSpringForceNEditField.Value;
            deflection = app.MaxDeflectionmmEditField.Value;
            UTS = app.UTSNmmEditField.Value;
            G = app.ModulusofrigidityGEditField.Value;
            C = app.SpringIndexCEditField.Value;
            tau = 0.5*UTS;
            K = ((4*C-1)/(4*C-4))+(0.615/C);
            d = round(sqrt((8*K*P*C)/(pi*tau)));
            D = C*d;
            N = round((deflection*G*d^4)/(8*P*D^3));
            Nt = N+(app.EndConditionDropDown.Value);% Type of spring end
            solid_length = Nt*d;
            actual_deflection = (8*P*N*D^3)/(G*d^4);
            total_gap = (Nt-1)*1;
            free_length = solid_length + total_gap + deflection;
            pitch = free_length/(Nt-1);
            k = (G*d^4)/(8*N*D^3);
            r_in = (D-d)/2;
            r_out  = (D+d)/2;
            L = free_length;
            loop = Nt;
    

            frac = 20;
            nNodes = 50;
            nCS = 1400;

            %plotOrigin([-2 2],[-2 2],[-1 6],[0 0 0]); axis off
        
            alpha = loop*360 + frac;


            th = linspace(0,2*pi,nNodes);
            r = 0.5*(r_out-r_in)*ones(1,nNodes);
            z = r.*cos(th);
            y = r.*sin(th)+r_in+0.5*(r_out-r_in);
            x = zeros(1,nNodes);

            dz = linspace(0,L,nCS);
            phi = linspace(0,alpha,nCS);
            X =[];
            Y =[];
            Z =[];
            for i=1:nCS
                temp_CS = rotationMatrix('z',phi(i)) * [x;y;z];
                X =[X; temp_CS(1,:)];app.A=X;
                Y =[Y; temp_CS(2,:)];app.B=Y;
                Z =[Z; temp_CS(3,:)+dz(i)];app.C=Z;
                plot3(app.UIAxes,X(i,:),Y(i,:),Z(i,:),'ok');
            end
            % solidifing 
            [X, Y, Z] = get_plates(X, Y, Z, nNodes, r_out, r_in, alpha, L) ;
            % doing surfacing in adjecent rings
            surf(app.UIAxes,X,Y,Z);


            function [X, Y, Z] = get_plates(X, Y, Z, nNodes, r_out, r_in, alpha, L) 
                plate1 = [0; 0.5*(r_out-r_in) + r_in; 0];
                plate2 = rotationMatrix('z', alpha)*plate1;
                plate1 = repmat(plate1, 1, nNodes); 
                plate2 = repmat (plate2, 1, nNodes);
                X = [plate1(1,:); X; plate2(1,:)]; 
                Y = [plate1(2,:); Y; plate2(2,:)];
                Z = [plate1(3,:); Z; plate2(3,:) + L];
            end


            function rotation_matrix = rotationMatrix(type,angle)
                angle = angle*(2*pi)/360;
                if type == 'x'
                rotation_matrix=[1     0         0
                                 0 cos(angle) -sin(angle)
                                 0 sin(angle) cos(angle) ];
                elseif type == 'y'
                    rotation_matrix=[cos(angle)       0    sin(angle)
                                        0             1       0
                                     -sin(angle)      0    cos(angle) ];
               elseif type == 'z'
                    rotation_matrix=[cos(angle) -sin(angle)    0;
                                     sin(angle)  cos(angle)    0;
                                       0            0          1 ];
                else
                    fprintf("Invailed input for type");
                end
            end


            function plotOrigin(rangeX,rangeY,rangeZ,offset)
                x0 = offset(1);
                y0 = offset(2);
                z0 = offset(3);
            
                if x0<rangeX(1)|| x0>rangeX(2)
                    fprintf('Origin is not within x axis range')
                elseif y0<rangeY(1)|| y0>rangeY(2)
                    fprintf('Origin is not within y axis range')
                    return
                elseif z0<rangeZ(1)|| z0>rangeZ(2)
                    fprintf('Origin is not within Z axis range')
                    return
                end
            
            
                plot3(rangeX+x0,[y0 y0],[z0,z0],'--r')
                axis equal
                hold on
                plot3([x0 x0],rangeY+y0,[z0,z0],'--g')
                plot3([x0 x0],[y0 y0],rangeZ+z0,'--b')
            end

        end

        % Button pushed function: ExportasSTLButton
        function ExportasSTLButtonPushed(app, event)
            loading();  %function calling
            loadingDone();

%---------------------------------------------------------------------------

            function loading()
                    cla(app.UIAxes);
                    loadingLine = rectangle(app.UIAxes, 'Position', [0, 0, 0, 0.1], 'FaceColor', 'blue');
    
    
                    axis(app.UIAxes, 'equal');
                    axis(app.UIAxes, [0, 1, 0, 1]);
    
    
                    numSteps = 100;  
                    for step = 1:numSteps
        
                        loadingLine.Position(3) = step / numSteps;
                        drawnow;  
                        
                    end
                    delete(loadingLine);
             end
            %-----------------------------------------           
            function loadingDone()
                app.UIAxes.Visible = 'on';
            
                P = app.MaxSpringForceNEditField.Value;
                deflection = app.MaxDeflectionmmEditField.Value;
                UTS = app.UTSNmmEditField.Value;
                G = app.ModulusofrigidityGEditField.Value;
                C = app.SpringIndexCEditField.Value;
                tau = 0.5*UTS;
                K = ((4*C-1)/(4*C-4))+(0.615/C);
                d = round(sqrt((8*K*P*C)/(pi*tau)));
                D = C*d;
                N = round((deflection*G*d^4)/(8*P*D^3));
                Nt = N+(app.EndConditionDropDown.Value);% Type of spring end
                solid_length = Nt*d;
                actual_deflection = (8*P*N*D^3)/(G*d^4);
                total_gap = (Nt-1)*1;
                free_length = solid_length + total_gap + deflection;
                pitch = free_length/(Nt-1);
                k = (G*d^4)/(8*N*D^3);
                r_in = (D-d)/2;
                r_out  = (D+d)/2;
                L = free_length;
                loop = Nt;
        
    
                frac = 20;
                nNodes = 50;
                nCS = 1400;

                %plotOrigin([-2 2],[-2 2],[-1 6],[0 0 0]); axis off
            
                alpha = loop*360 + frac;
    
    
                th = linspace(0,2*pi,nNodes);
                r = 0.5*(r_out-r_in)*ones(1,nNodes);
                z = r.*cos(th);
                y = r.*sin(th)+r_in+0.5*(r_out-r_in);
                x = zeros(1,nNodes);
    
                dz = linspace(0,L,nCS);
                phi = linspace(0,alpha,nCS);
                X =[];
                Y =[];
                Z =[];
                for i=1:nCS
                    temp_CS = rotationMatrix('z',phi(i)) * [x;y;z];
                    X =[X; temp_CS(1,:)];app.A=X;
                    Y =[Y; temp_CS(2,:)];app.B=Y;
                    Z =[Z; temp_CS(3,:)+dz(i)];app.C=Z;
                    plot3(app.UIAxes,X(i,:),Y(i,:),Z(i,:),'ok');
                end
                    % solidifing 
                [X, Y, Z] = get_plates(X, Y, Z, nNodes, r_out, r_in, alpha, L) ;
                % doing surfacing in adjecent rings
                surf(app.UIAxes,X,Y,Z);


                function [X, Y, Z] = get_plates(X, Y, Z, nNodes, r_out, r_in, alpha, L) 
                    plate1 = [0; 0.5*(r_out-r_in) + r_in; 0];
                    plate2 = rotationMatrix('z', alpha)*plate1;
                    plate1 = repmat(plate1, 1, nNodes); 
                    plate2 = repmat (plate2, 1, nNodes);
                    X = [plate1(1,:); X; plate2(1,:)]; 
                    Y = [plate1(2,:); Y; plate2(2,:)];
                    Z = [plate1(3,:); Z; plate2(3,:) + L];
                end


                function rotation_matrix = rotationMatrix(type,angle)
                    angle = angle*(2*pi)/360;
                    if type == 'x'
                    rotation_matrix=[1     0         0
                                     0 cos(angle) -sin(angle)
                                     0 sin(angle) cos(angle) ];
                    elseif type == 'y'
                        rotation_matrix=[cos(angle)       0    sin(angle)
                                            0             1       0
                                         -sin(angle)      0    cos(angle) ];
                   elseif type == 'z'
                        rotation_matrix=[cos(angle) -sin(angle)    0;
                                         sin(angle)  cos(angle)    0;
                                           0            0          1 ];
                    else
                        fprintf("Invailed input for type");
                    end
                end


                function plotOrigin(rangeX,rangeY,rangeZ,offset)
                    x0 = offset(1);
                    y0 = offset(2);
                    z0 = offset(3);
                
                    if x0<rangeX(1)|| x0>rangeX(2)
                        fprintf('Origin is not within x axis range')
                    elseif y0<rangeY(1)|| y0>rangeY(2)
                        fprintf('Origin is not within y axis range')
                        return
                    elseif z0<rangeZ(1)|| z0>rangeZ(2)
                        fprintf('Origin is not within Z axis range')
                        return
                    end
                
                
                    plot3(rangeX+x0,[y0 y0],[z0,z0],'--r')
                    axis equal
                    hold on
                    plot3([x0 x0],rangeY+y0,[z0,z0],'--g')
                    plot3([x0 x0],[y0 y0],rangeZ+z0,'--b')
                end

            end


%-----------------------------Main----------------------------------


            X = app.A;    
            Y = app.B;
            Z = app.C;

            Plot3ToSTL(X, Y, Z);

            function Plot3ToSTL(X, Y, Z)
                outputDirectory = '';
                name = [outputDirectory 'Spring.stl'];
                output = fopen(name, 'w');
                fprintf(output, ['solid ' name '\n']);

                rows = size(X, 1);
                columns = size(X, 2);

                for i = 1:rows - 1
                    for j = 1:columns - 1
                        v1 = [X(i, j), Y(i, j), Z(i, j)];
                        v2 = [X(i, j + 1), Y(i, j + 1), Z(i, j + 1)];
                        v3 = [X(i + 1, j + 1), Y(i + 1, j + 1), Z(i + 1, j + 1)];

                        vector1 = v1 - v2;
                        vector2 = v1 - v3;
                        norm = cross(vector1, vector2);
                        mag = sqrt(norm(1)^2 + norm(2)^2 + norm(3)^2);
                        unit_normal = norm / mag;

                        fprintf(output, 'facet normal %.7E %.7E %.7E\n', unit_normal); 
                        fprintf(output, 'outer loop\n');
                        fprintf(output, 'vertex %.7E %.7E %.7E\n', v1);
                        fprintf(output, 'vertex %.7E %.7E %.7E\n', v2);
                        fprintf(output, 'vertex %.7E %.7E %.7E\n', v3);
                        fprintf(output, 'endloop\n');
                        fprintf(output, 'endfacet\n');

                        v1 = [X(i + 1, j + 1), Y(i + 1, j + 1), Z(i + 1, j + 1)];
                        v2 = [X(i + 1, j), Y(i + 1, j), Z(i + 1, j)];
                        v3 = [X(i, j), Y(i, j), Z(i, j)];

                        vector1 = v1 - v2;
                        vector2 = v1 - v3;
                        norm = cross(vector1, vector2);
                        mag = sqrt(norm(1)^2 + norm(2)^2 + norm(3)^2);
                        unit_normal = norm / mag;

                        fprintf(output, 'facet normal %.7E %.7E %.7E\n', unit_normal);
                        fprintf(output, 'outer loop\n');
                        fprintf(output, 'vertex %.7E %.7E %.7E\n', v1);
                        fprintf(output, 'vertex %.7E %.7E %.7E\n', v2);
                        fprintf(output, 'vertex %.7E %.7E %.7E\n', v3);
                        fprintf(output, 'endloop\n');
                        fprintf(output, 'endfacet\n');
                    end
                end
                fprintf(output, 'endsolid\n');
                fclose(output);
                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'autumn');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CAD Model')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            colormap(app.UIAxes, 'autumn')
            app.MaxSpringForceNEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSpringForceNEditFieldLabel.Text = 'Max Spring Force (N)';
            app.MaxSpringForceNEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxSpringForceNEditField.Value = 1500;
            app.MaxDeflectionmmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxDeflectionmmEditFieldLabel.Text = 'Max Deflection (mm)';
            app.MaxDeflectionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxDeflectionmmEditField.Value = 50;
            app.SpringIndexCEditFieldLabel = uilabel(app.UIFigure);
            app.SpringIndexCEditFieldLabel.Text = 'Spring Index :C';
            app.SpringIndexCEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringIndexCEditField.Value = 8;
            app.SpecifyMaterialItsPropertiesLabel = uilabel(app.UIFigure);
            app.SpecifyMaterialItsPropertiesLabel.Text = 'Specify Material & Its Properties:';
            app.DefineConstraintsLabel = uilabel(app.UIFigure);
            app.DefineConstraintsLabel.Text = 'Define Constraints:';
            app.CalculateotherValueGenerateCADButton = uibutton(app.UIFigure, 'push');
            app.CalculateotherValueGenerateCADButton.ButtonPushedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CalculateotherValueGenerateCADButton.Text = 'Calculate other Value & Generate CAD';
            app.UTSLabel = uilabel(app.UIFigure);
            app.UTSLabel.Text = 'UTS (N/mm²)';
            app.UTSNmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.UTSNmmEditField.Value = 1090;
            app.ModulusofrigidityGEditFieldLabel = uilabel(app.UIFigure);
            app.ModulusofrigidityGEditFieldLabel.Text = ' Modulus of rigidity :G';
            app.ModulusofrigidityGEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulusofrigidityGEditField.Value = 81370;
            app.EndConditionDropDownLabel = uilabel(app.UIFigure);
            app.EndConditionDropDownLabel.Text = 'End Condition';
            app.EndConditionDropDown = uidropdown(app.UIFigure);
            app.EndConditionDropDown.Items = {'Plain ends', 'Ground ends', 'Squared ends', 'Squared and ground ends'};
            app.EndConditionDropDown.ItemsData = [0 0 2 2];
            app.EndConditionDropDown.Placeholder = '--- Choose End Condition ---';
            app.EndConditionDropDown.Value = 0;
            app.ExportasSTLButton = uibutton(app.UIFigure, 'push');
            app.ExportasSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasSTLButtonPushed, true);
            app.ExportasSTLButton.Text = 'Export as STL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spring_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end